classdef manga_panel_cleaner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure  matlab.ui.Figure
        MATLABCLEANINGLabel             matlab.ui.control.Label
        SystemPerformancePanel          matlab.ui.container.Panel
        StructuralSimilarityIndexLabel  matlab.ui.control.Label
        SSIM                            matlab.ui.control.Label
        ImageEnhancementFactorLabel     matlab.ui.control.Label
        MeanSquareErrorLabel            matlab.ui.control.Label
        PeakSignaltonoiseRatioLabel     matlab.ui.control.Label
        TimeElapsedLabel                matlab.ui.control.Label
        IEF                             matlab.ui.control.Label
        MSE                             matlab.ui.control.Label
        PSNR                            matlab.ui.control.Label
        TE                              matlab.ui.control.Label
        SELECTMANUALLYCLEANEDFILEButton  matlab.ui.control.Button
        SELECTRAWSCANButton             matlab.ui.control.Button
        CLEARButton                     matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        MATLABCleanAxes                 matlab.ui.control.UIAxes
        OfficialAxes                    matlab.ui.control.UIAxes
        RawAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        net = load('net.mat');
        raw_image;
        official_image;
        raw_flag = 0;
        official_flag = 0;
        cleaned_image;
    end
    
    methods (Access = private)
        function X = clean(app)
 
            % Initialize Progress Bar
            fig = uifigure;
            progress = uiprogressdlg(fig,'Title','Performing tasks',...
            'Message','Processing the image...');
            pause(2)
            
            % Gaussian Filter to smoothen the image
            tic
            X = imgaussfilt(app.raw_image,0.55,'FilterSize',5,'Padding','circular');
            te = toc; % time elapsed
            
            % Progress update
            progress.Value = .125;
            progress.Message = 'Sharpening the image...';
            pause(2)
            
            % Unsharp masking
            tic
            X = imsharpen(X,'Amount',0.50);
            te = te + toc;
            
            % Progress update
            progress.Value = .25;
            progress.Message = 'Levelling the image...';
            pause(2)  
            
            % Adjust image levelling
            tic
            X = imadjust(X);
            te = te + toc;
            
            % Progress update
            progress.Value = .375;
            progress.Message = 'Denoising the image...';
            pause(1) 
            
            % Denoise image
            tic
            X = denoiseImage(X,app.net.net);
            te = te + toc;
            
            % Progress update
            progress.Value = .60;
            progress.Message = 'Applying filters...';
            pause(1) 
            
            % Median filtering
            tic
            X = medfilt2(X,[2 2],'symmetric');
            te = te + toc;
            
            % Progress update
            progress.Value = .70;
            progress.Message = 'Levelling the image...';
            pause(1) 
            
            % Adjust image levelling
            tic
            X = imadjust(X, ([40, 233]/255),[]);
            te = te + toc;
            
             % Progress update
            progress.Value = .80;
            progress.Message = 'Sharpening the image...';
            pause(1) 
            
            % Unsharp masking
            tic
            X = imsharpen(X,'Amount',0.50);
            te = te + toc;
            
            % Progress update
            progress.Value = .90;
            progress.Message = 'Smoothening the image...';
            pause(1) 
            
            % Gaussian Filter to smoothen the image
            tic
            X = imgaussfilt(X,0.50,'FilterSize',5,'Padding','replicate');
            te = te + toc;
            
            % Progress update
            progress.Value = 1.0;
            progress.Message = 'Finishing...';
            pause(1)
            
            % Close progress box
            close(fig)
            
            % Pass file for save purposes
            app.cleaned_image = X;
            
            performance(app,te);
        end
        
        function [te,peaksnr,err,ief,ssimval] = performance(app,timeElapsed)
            % Get TE
            te = timeElapsed;
            
            % Get PSNR
            peaksnr = psnr(app.cleaned_image,app.official_image);
            
            % Get MSE
            err = immse(app.cleaned_image,app.official_image);
            
            % Get IEF
            err0 = immse(app.raw_image,app.official_image);
            err1 = immse(app.cleaned_image,app.official_image);
            ief = err0/err1;
            
            % Get SSIM
            ssimval = ssim(app.cleaned_image,app.official_image);
            
            % Update display values
            app.TE.Text = num2str(te);
            app.PSNR.Text = num2str(peaksnr);
            app.MSE.Text = num2str(err);
            app.IEF.Text = num2str(ief);
            app.SSIM.Text = num2str(ssimval);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SELECTRAWSCANButton
        function SELECTRAWSCANButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png'},"Open file");
            % If file exists, do
            if(filename)
                fullpathname = strcat(pathname,filename);
                app.raw_image = imread(fullpathname);
                imshow(app.raw_image,'Parent',app.RawAxes);
                app.raw_flag = 1;
            end
            
            if(app.raw_flag == 1 && app.official_flag == 1)
                app.STARTButton.Enable = 'on';
            end
        end

        % Button pushed function: SELECTMANUALLYCLEANEDFILEButton
        function SELECTMANUALLYCLEANEDFILEButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png'},"Open file");
            % If file exists, do
            if(filename)
                fullpathname = strcat(pathname,filename);
                app.official_image = imread(fullpathname);
                imshow(app.official_image,'Parent',app.OfficialAxes);
                app.official_flag = 1;
            end
            
            if(app.raw_flag == 1 && app.official_flag == 1)
                app.STARTButton.Enable = 'on';
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.RawAxes,"reset");
            cla(app.OfficialAxes,"reset");
            cla(app.MATLABCleanAxes,"reset");
            
            app.RawAxes.XTick = [];
            app.RawAxes.XTickLabel = [];
            app.RawAxes.YTick = [];
            app.RawAxes.YTickLabel = [];
            
            app.OfficialAxes.XTick = [];
            app.OfficialAxes.XTickLabel = [];
            app.OfficialAxes.YTick = [];
            app.OfficialAxes.YTickLabel = [];
            
            app.MATLABCleanAxes.XTick = [];
            app.MATLABCleanAxes.XTickLabel = [];
            app.MATLABCleanAxes.YTick = [];
            app.MATLABCleanAxes.YTickLabel = [];
            
            app.STARTButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.raw_flag = 0;
            app.official_flag = 0;
            
            app.TE.Text = '';
            app.PSNR.Text = '';
            app.MSE.Text = '';
            app.IEF.Text = '';
            app.SSIM.Text = '';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable = 'off';
            imshow(clean(app),'Parent',app.MATLABCleanAxes);
            app.SAVEButton.Enable = 'on';
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            [filename, ~] = uiputfile('*.png');
            if(filename)
                imwrite(app.cleaned_image,filename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure = uifigure('Visible', 'off');
            app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure.Name = 'Manga Panel Cleaner | DSIGPRO | Bayeta, Megino, Parco';
            app.RawAxes = uiaxes(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure);
            app.RawAxes.XTick = [];
            app.RawAxes.YTick = [];
            app.RawAxes.ZTick = [];
            app.OfficialAxes = uiaxes(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure);
            app.OfficialAxes.XTick = [];
            app.OfficialAxes.YTick = [];
            app.OfficialAxes.ZTick = [];
            app.MATLABCleanAxes = uiaxes(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure);
            app.MATLABCleanAxes.XTick = [];
            app.MATLABCleanAxes.YTick = [];
            app.MATLABCleanAxes.ZTick = [];
            app.STARTButton = uibutton(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Tooltip = {'Start Manga Cleaning Process, make sure raw and official are selected.'};
            app.STARTButton.Text = 'START';
            app.SAVEButton = uibutton(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Tooltip = {'Save MATLAB Cleaning image result.'};
            app.SAVEButton.Text = 'SAVE';
            app.CLEARButton = uibutton(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Tooltip = {'Clear selected and processed images.'};
            app.CLEARButton.Text = 'CLEAR';
            app.SELECTRAWSCANButton = uibutton(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure, 'push');
            app.SELECTRAWSCANButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTRAWSCANButtonPushed, true);
            app.SELECTRAWSCANButton.Tooltip = {'Select raw scan image to compare MATLAB Cleaning image results to.'};
            app.SELECTRAWSCANButton.Text = 'SELECT RAW SCAN';
            app.SELECTMANUALLYCLEANEDFILEButton = uibutton(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure, 'push');
            app.SELECTMANUALLYCLEANEDFILEButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTMANUALLYCLEANEDFILEButtonPushed, true);
            app.SELECTMANUALLYCLEANEDFILEButton.Tooltip = {'Select Official Release Image'};
            app.SELECTMANUALLYCLEANEDFILEButton.Text = 'SELECT MANUALLY CLEANED FILE';
            app.SystemPerformancePanel = uipanel(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure);
            app.SystemPerformancePanel.Title = 'System Performance';
            app.TE = uilabel(app.SystemPerformancePanel);
            app.TE.Text = '';
            app.PSNR = uilabel(app.SystemPerformancePanel);
            app.PSNR.Text = '';
            app.MSE = uilabel(app.SystemPerformancePanel);
            app.MSE.Text = '';
            app.IEF = uilabel(app.SystemPerformancePanel);
            app.IEF.Text = '';
            app.TimeElapsedLabel = uilabel(app.SystemPerformancePanel);
            app.TimeElapsedLabel.Text = 'Time Elapsed';
            app.PeakSignaltonoiseRatioLabel = uilabel(app.SystemPerformancePanel);
            app.PeakSignaltonoiseRatioLabel.Text = 'Peak Signal-to-noise Ratio';
            app.MeanSquareErrorLabel = uilabel(app.SystemPerformancePanel);
            app.MeanSquareErrorLabel.Text = 'Mean Square Error';
            app.ImageEnhancementFactorLabel = uilabel(app.SystemPerformancePanel);
            app.ImageEnhancementFactorLabel.Text = 'Image Enhancement Factor';
            app.SSIM = uilabel(app.SystemPerformancePanel);
            app.SSIM.Text = '';
            app.StructuralSimilarityIndexLabel = uilabel(app.SystemPerformancePanel);
            app.StructuralSimilarityIndexLabel.Text = 'Structural Similarity Index';
            app.MATLABCLEANINGLabel = uilabel(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure);
            app.MATLABCLEANINGLabel.Text = 'MATLAB CLEANING';
            % Show the figure after all components are created
            app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manga_panel_cleaner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MangaPanelCleanerDSIGPROBayetaMeginoParcoUIFigure)
        end
    end
end