classdef version3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MATLABCLEANINGLabel          matlab.ui.control.Label
        SystemPerformancePanel       matlab.ui.container.Panel
        OutputParameter4Label        matlab.ui.control.Label
        OutputParameter3Label        matlab.ui.control.Label
        OutputParameter2Label        matlab.ui.control.Label
        OutputParameter1Label        matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        SELECTOFFICIALRELEASEButton  matlab.ui.control.Button
        SELECTRAWSCANButton          matlab.ui.control.Button
        CLEARButton                  matlab.ui.control.Button
        SAVEButton                   matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        MATLABCleanAxes              matlab.ui.control.UIAxes
        OfficialAxes                 matlab.ui.control.UIAxes
        RawAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        net = load('net.mat');
        raw_image;
        official_image;
        raw_flag = 0;
        official_flag = 0;
        cleaned_image;
    end
    
    methods (Access = private)
        function X = clean(app)
            % Initialize Progress Bar
            fig = uifigure;
            progress = uiprogressdlg(fig,'Title','Performing tasks',...
            'Message','Processing the image...');
            pause(2)
            
            % Gaussian Filter to smoothen the image
            X = imgaussfilt(app.raw_image,0.55,'FilterSize',5,'Padding','circular');
            
            % Progress update
            progress.Value = .125;
            progress.Message = 'Sharpening the image...';
            pause(2)
            
            % Unsharp masking
            X = imsharpen(X,'Amount',0.50);
            
            % Progress update
            progress.Value = .25;
            progress.Message = 'Levelling the image...';
            pause(2)  
            
            % Adjust image levelling
            X = imadjust(X);
            
            % Progress update
            progress.Value = .375;
            progress.Message = 'Denoising the image...';
            pause(1) 
            
            % Denoise image
            X = denoiseImage(X,app.net.net);
            
            % Progress update
            progress.Value = .60;
            progress.Message = 'Applying filters...';
            pause(1) 
            
            % Median filtering
            X = medfilt2(X,[2 2],'symmetric');
            
            % Progress update
            progress.Value = .70;
            progress.Message = 'Levelling the image...';
            pause(1) 
            
            % Adjust image levelling
            X = imadjust(X, ([40, 233]/255),[]);
            
             % Progress update
            progress.Value = .80;
            progress.Message = 'Sharpening the image...';
            pause(1) 
            
            % Unsharp masking
            X = imsharpen(X,'Amount',0.50);
            
            % Progress update
            progress.Value = .90;
            progress.Message = 'Smoothening the image...';
            pause(1) 
            
            % Gaussian Filter to smoothen the image
            X = imgaussfilt(X,0.50,'FilterSize',5,'Padding','replicate');
            
            % Progress update
            progress.Value = 1.0;
            progress.Message = 'Finishing...';
            pause(1)
            
            % Close progress box
            close(fig)
            
            app.cleaned_image = X;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SELECTRAWSCANButton
        function SELECTRAWSCANButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png'},"Open file");
            % If file exists, do
            if(filename)
                fullpathname = strcat(pathname,filename);
                app.raw_image = imread(fullpathname);
                imshow(app.raw_image,'Parent',app.RawAxes);
                app.raw_flag = 1;
            end
            
            if(app.raw_flag == 1 && app.official_flag == 1)
                app.STARTButton.Enable = 'on';
            end
        end

        % Button pushed function: SELECTOFFICIALRELEASEButton
        function SELECTOFFICIALRELEASEButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png'},"Open file");
            % If file exists, do
            if(filename)
                fullpathname = strcat(pathname,filename);
                app.official_image = imread(fullpathname);
                imshow(app.raw_image,'Parent',app.OfficialAxes);
                app.official_flag = 1;
            end
            
            if(app.raw_flag == 1 && app.official_flag == 1)
                app.STARTButton.Enable = 'on';
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.RawAxes,"reset");
            cla(app.OfficialAxes,"reset");
            cla(app.MATLABCleanAxes,"reset");
            
            app.RawAxes.XTick = [];
            app.RawAxes.XTickLabel = [];
            app.RawAxes.YTick = [];
            app.RawAxes.YTickLabel = [];
            
            app.OfficialAxes.XTick = [];
            app.OfficialAxes.XTickLabel = [];
            app.OfficialAxes.YTick = [];
            app.OfficialAxes.YTickLabel = [];
            
            app.MATLABCleanAxes.XTick = [];
            app.MATLABCleanAxes.XTickLabel = [];
            app.MATLABCleanAxes.YTick = [];
            app.MATLABCleanAxes.YTickLabel = [];
            
            app.STARTButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable = 'off';
            imshow(clean(app),'Parent',app.MATLABCleanAxes);
            app.SAVEButton.Enable = 'on';
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            [filename, ~] = uiputfile('*.png');
            if(filename)
                imwrite(app.cleaned_image,filename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RawAxes = uiaxes(app.UIFigure);
            app.RawAxes.XTick = [];
            app.RawAxes.YTick = [];
            app.RawAxes.ZTick = [];
            app.OfficialAxes = uiaxes(app.UIFigure);
            app.OfficialAxes.XTick = [];
            app.OfficialAxes.YTick = [];
            app.OfficialAxes.ZTick = [];
            app.MATLABCleanAxes = uiaxes(app.UIFigure);
            app.MATLABCleanAxes.XTick = [];
            app.MATLABCleanAxes.YTick = [];
            app.MATLABCleanAxes.ZTick = [];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Tooltip = {'Start Manga Cleaning Process, make sure raw and official are selected.'};
            app.STARTButton.Text = 'START';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Tooltip = {'Save MATLAB Cleaning image result.'};
            app.SAVEButton.Text = 'SAVE';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Tooltip = {'Clear selected and processed images.'};
            app.CLEARButton.Text = 'CLEAR';
            app.SELECTRAWSCANButton = uibutton(app.UIFigure, 'push');
            app.SELECTRAWSCANButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTRAWSCANButtonPushed, true);
            app.SELECTRAWSCANButton.Tooltip = {'Select raw scan image to compare MATLAB Cleaning image results to.'};
            app.SELECTRAWSCANButton.Text = 'SELECT RAW SCAN';
            app.SELECTOFFICIALRELEASEButton = uibutton(app.UIFigure, 'push');
            app.SELECTOFFICIALRELEASEButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTOFFICIALRELEASEButtonPushed, true);
            app.SELECTOFFICIALRELEASEButton.Tooltip = {'Select Official Release Image'};
            app.SELECTOFFICIALRELEASEButton.Text = 'SELECT OFFICIAL RELEASE';
            app.SystemPerformancePanel = uipanel(app.UIFigure);
            app.SystemPerformancePanel.Title = 'System Performance';
            app.Label = uilabel(app.SystemPerformancePanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.SystemPerformancePanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.SystemPerformancePanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.SystemPerformancePanel);
            app.Label_4.Text = '';
            app.OutputParameter1Label = uilabel(app.SystemPerformancePanel);
            app.OutputParameter1Label.Text = 'Output Parameter 1';
            app.OutputParameter2Label = uilabel(app.SystemPerformancePanel);
            app.OutputParameter2Label.Text = 'Output Parameter 2';
            app.OutputParameter3Label = uilabel(app.SystemPerformancePanel);
            app.OutputParameter3Label.Text = 'Output Parameter 3';
            app.OutputParameter4Label = uilabel(app.SystemPerformancePanel);
            app.OutputParameter4Label.Text = 'Output Parameter 4';
            app.MATLABCLEANINGLabel = uilabel(app.UIFigure);
            app.MATLABCLEANINGLabel.Text = 'MATLAB CLEANING';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = version3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end